classdef EE302_Group3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        k1_rEditFieldLabel             matlab.ui.control.Label
        k1_rEditField                  matlab.ui.control.NumericEditField
        k2_rEditFieldLabel             matlab.ui.control.Label
        k2_rEditField                  matlab.ui.control.NumericEditField
        k3_rEditFieldLabel             matlab.ui.control.Label
        k3_rEditField                  matlab.ui.control.NumericEditField
        k4_rEditFieldLabel             matlab.ui.control.Label
        k4_rEditField                  matlab.ui.control.NumericEditField
        INPUTSLabel                    matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        SystemLabel                    matlab.ui.control.Label
        k1_iEditFieldLabel             matlab.ui.control.Label
        k1_iEditField                  matlab.ui.control.NumericEditField
        k2_iEditFieldLabel             matlab.ui.control.Label
        k2_iEditField                  matlab.ui.control.NumericEditField
        k3_iEditFieldLabel             matlab.ui.control.Label
        k3_iEditField                  matlab.ui.control.NumericEditField
        k4_iEditFieldLabel             matlab.ui.control.Label
        k4_iEditField                  matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        ConditionsforStableLabel       matlab.ui.control.Label
        ConditionsforStableTextArea    matlab.ui.control.TextArea
        Column1DataTextAreaLabel       matlab.ui.control.Label
        Column1DataTextArea            matlab.ui.control.TextArea
        ConditionsforUnstableTextAreaLabel  matlab.ui.control.Label
        ConditionsforMarginallyStableLabel  matlab.ui.control.Label
        ConditionsforUnstableTextArea  matlab.ui.control.TextArea
        ResultsLabel                   matlab.ui.control.Label
        ConditionsforMarginallyStableTextArea  matlab.ui.control.TextArea
        TFDenominatorPolynomialLabel   matlab.ui.control.Label
        TFDenominatorPolynomialTextArea  matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Take inputs from the panel into variables.
            disp('Run')
            k1_r = app.k1_rEditField.Value;
            k2_r = app.k2_rEditField.Value;
            k3_r = app.k3_rEditField.Value;
            k4_r = app.k4_rEditField.Value;
            k1_i = app.k1_iEditField.Value;
            k2_i = app.k2_iEditField.Value;
            k3_i = app.k3_iEditField.Value;
            k4_i = app.k4_iEditField.Value;
            
            kvector = [k1_r+k1_i*1j k2_r+k2_i*1j k3_r+k3_i*1j k4_r+k4_i*1j];
            disp(kvector)
            
            % K is a symbol as we have to compute its range
            syms K
            assume(K,{'real', 'positive'})
            
            % The root polynomial looks like as
            % a*s^4 + b*s^3 c*s^2 + d*s^1 + e + K = 0
            %
            % Now we will commpute values of these coeff
            
            a=1;
            b=0;
            c=0;
            d=0;
            e=1;
            
            for i = 1:4
                b = b + kvector(i);
                e = e*kvector(i);
                for j = i+1:4
                    c = c + kvector(i)*kvector(j);
                    for k = j+1:4
                        d = d + kvector(i)*kvector(j)*kvector(k);
                    end
                end
            end
            
            tf_den_poly = [a b c d e+K];
            app.TFDenominatorPolynomialTextArea.Value=char(tf_den_poly);
            
            if (isreal(tf_den_poly)==0)
                app.ConditionsforStableTextArea.Value='Imaginary coefficient polynomial';
                return
            end
            
            % Now we will compute the RH table
            % 1) a  c  e+K
            % 2) b  d  0
            % 3) x  y  0
            % 4) z  0  0
            % 5) w  0  0
            
            %% ROW 2
            
            % set flags to 1 if elements in row 2 are zero
            if(b==0)
                flag_b_zero=1;
            else
                flag_b_zero=0;
            end
            
            if(d==0)
                flag_d_zero=1;
            else
                flag_d_zero=0;
            end
            
            
            % The first element of any row of the Routh array is zero.
            if(flag_b_zero==1 && flag_d_zero==0)
                syms b
                assume(b,{'real', 'positive'})
                flag_take_limit_b = 1;
            else
                flag_take_limit_b = 0;
            end
            
            % All the elements of second row of the Routh array are zero.
            if(flag_b_zero==1 && flag_d_zero==1)
                % Auxiliary equation: a*s^4 + c*s^2 + e + K
                % diff: 4*a*s^3 + 2*c*s
                flag_row2_zero = 1;
                b = 4*a;
                d = 2*c;
            else
                flag_row2_zero = 0;
            end
            
            %% ROW 3
            
            % using algo for RH table for row 3
            x = (b*c-a*d)/b;
            y = (e+K);
            
            % set flags to 1 if elements in row 3 are zero
            if(x==0)
                flag_x_zero=1;
            else
                flag_x_zero=0;
            end
            
            % The first element of row 3 of the Routh array is zero.
            if(flag_x_zero==1)
                syms x
                assume(x,{'real', 'positive'})
                flag_take_limit_x = 1;
            else
                flag_take_limit_x = 0;
            end
            
            
            %% ROW 4
            
            % Computing row 4
            z = (x*d-y*b)/x;
            
            if(z==0)
                flag_z=1;
            else
                flag_z=0;
            end
            
            % All the elements of 4th row of the Routh array are zero.
            if(flag_z==1)
                % Auxiliary equation: x*s^2 + y
                % diff: x*s
                z=x;
                flag_row4_zero = 1;
            else
                flag_row4_zero = 0;
            end
            
            %% ROW 5
            
            w = y;
            
            %% Apply limits
            
            % column 1 of the RH table
            col1 = [a b x z w];
            col1_lim = [];
            disp(col1)
            if(flag_take_limit_b == 1)
                col1_lim = limit(col1,b,0,'right');
            end
            
            if(flag_take_limit_x == 1)
                col1_lim = limit(col1,x,0,'right');
            end
            disp(col1_lim)
            app.Column1DataTextArea.Value = char(col1);
            
            %% Check if system is unstable independent of K
            
            % The first 3 elements in the col are independent of K, lets see if the
            % system is unstable independent of K
            unstable_independent = 0;
            if (sign(col1(1))*sign(col1(2))==-1 || sign(col1(2))*sign(col1(3))==-1)
                
                app.ConditionsforUnstableTextArea.Value='System is unstable independent of the value of K';
                disp('System is unstable independent of the value of K')
                app.ConditionsforStableTextArea.Value='';
                
                return
            end
            
            %% Find conditions on K for system to be stable.
            
            if (unstable_independent == 0)
                eqns = [col1(2)>0 col1(3)>0 col1(4)>0 col1(5)>0];
                [~, ~, stable_cond] = solve(eqns,K,'ReturnConditions',true,'IgnoreAnalyticConstraints',true);
                if(isempty(stable_cond))
                    app.ConditionsforStableTextArea.Value='NO CONDITIONS RETURNED FOR STABLE';
                else
                    app.ConditionsforStableTextArea.Value=char(stable_cond);
                end
            end
            
            %% Find conditions on K for system to be unstable.
            
            eqn1 = sign(col1(1)) ~= sign(col1(2));
            eqn2 = sign(col1(2)) ~= sign(col1(3));
            eqn3 = sign(col1(3)) ~= sign(col1(4));
            eqn4 = sign(col1(4)) ~= sign(col1(5));
            [~, ~, unstable_cond_1] = solve(eqn1,K,'ReturnConditions',true,'IgnoreAnalyticConstraints',true);
            [~, ~, unstable_cond_2] = solve(eqn2,K,'ReturnConditions',true,'IgnoreAnalyticConstraints',true);
            [~, ~, unstable_cond_3] = solve(eqn3,K,'ReturnConditions',true,'IgnoreAnalyticConstraints',true);
            [~, ~, unstable_cond_4] = solve(eqn4,K,'ReturnConditions',true,'IgnoreAnalyticConstraints',true);
            eqns = [unstable_cond_1 unstable_cond_2 unstable_cond_3 unstable_cond_4];
            disp('Unstable conds')
            disp(eqns)
            [~, ~, unstable_cond] = solve(eqns,'ReturnConditions',true,'IgnoreAnalyticConstraints',true);
            app.ConditionsforUnstableTextArea.Value=char(unstable_cond);
            disp(unstable_cond)
            
            
            %% Values of K for marginally stable system
            
            
            % ROW 2 or ROW 4 ZERO
            
            if(flag_x_zero==1)
                eqn = y==0;
                [~, ~, marg_cond] = solve(eqn,'ReturnConditions',true,'IgnoreAnalyticConstraints',true);
                app.ConditionsforMarginallyStableTextArea.Value=char(marg_cond);
            else
                eqn = z==0;
                [~, ~, marg_cond] = solve(eqn,'ReturnConditions',true,'IgnoreAnalyticConstraints',true);
                app.ConditionsforMarginallyStableTextArea.Value=char(marg_cond);
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.k1_rEditField.Value = 0;
            app.k2_rEditField.Value = 0;
            app.k3_rEditField.Value = 0;
            app.k4_rEditField.Value = 0;
            app.Column1DataTextArea.Value = ' ';
            app.ConditionsforMarginallyStableTextArea.Value = ' ';
            app.ConditionsforStableTextArea.Value = ' ';
            app.ConditionsforUnstableTextArea.Value = ' ';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {422, 422};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {374, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.k1_rEditFieldLabel = uilabel(app.LeftPanel);
            app.k1_rEditFieldLabel.Text = 'k1_r';
            app.k1_rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k2_rEditFieldLabel = uilabel(app.LeftPanel);
            app.k2_rEditFieldLabel.Text = 'k2_r';
            app.k2_rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k2_rEditField.Value = 2;
            app.k3_rEditFieldLabel = uilabel(app.LeftPanel);
            app.k3_rEditFieldLabel.Text = 'k3_r';
            app.k3_rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k3_rEditField.Value = 3;
            app.k4_rEditFieldLabel = uilabel(app.LeftPanel);
            app.k4_rEditFieldLabel.Text = 'k4_r';
            app.k4_rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k4_rEditField.Value = 2;
            app.INPUTSLabel = uilabel(app.LeftPanel);
            app.INPUTSLabel.Text = 'INPUTS';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'System.png';
            app.SystemLabel = uilabel(app.LeftPanel);
            app.SystemLabel.Text = 'System';
            app.k1_iEditFieldLabel = uilabel(app.LeftPanel);
            app.k1_iEditFieldLabel.Text = 'k1_i';
            app.k1_iEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k2_iEditFieldLabel = uilabel(app.LeftPanel);
            app.k2_iEditFieldLabel.Text = 'k2_i';
            app.k2_iEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k3_iEditFieldLabel = uilabel(app.LeftPanel);
            app.k3_iEditFieldLabel.Text = 'k3_i';
            app.k3_iEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k4_iEditFieldLabel = uilabel(app.LeftPanel);
            app.k4_iEditFieldLabel.Text = 'k4_i';
            app.k4_iEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.ConditionsforStableLabel = uilabel(app.RightPanel);
            app.ConditionsforStableLabel.Text = 'Conditions for Stable';
            app.ConditionsforStableTextArea = uitextarea(app.RightPanel);
            app.Column1DataTextAreaLabel = uilabel(app.RightPanel);
            app.Column1DataTextAreaLabel.Text = 'Column1 Data';
            app.Column1DataTextArea = uitextarea(app.RightPanel);
            app.ConditionsforUnstableTextAreaLabel = uilabel(app.RightPanel);
            app.ConditionsforUnstableTextAreaLabel.Text = {'Conditions for'; 'Unstable'};
            app.ConditionsforMarginallyStableLabel = uilabel(app.RightPanel);
            app.ConditionsforMarginallyStableLabel.Text = {'Conditions for '; 'Marginally Stable'};
            app.ConditionsforUnstableTextArea = uitextarea(app.RightPanel);
            app.ResultsLabel = uilabel(app.RightPanel);
            app.ResultsLabel.Text = 'Results';
            app.ConditionsforMarginallyStableTextArea = uitextarea(app.RightPanel);
            app.TFDenominatorPolynomialLabel = uilabel(app.RightPanel);
            app.TFDenominatorPolynomialLabel.Text = {'TF Denominator '; 'Polynomial'};
            app.TFDenominatorPolynomialTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE302_Group3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end