classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        k1Label                         matlab.ui.control.Label
        k1EditField                     matlab.ui.control.NumericEditField
        k2EditFieldLabel                matlab.ui.control.Label
        k2EditField                     matlab.ui.control.NumericEditField
        k3EditFieldLabel                matlab.ui.control.Label
        k3EditField                     matlab.ui.control.NumericEditField
        k4EditFieldLabel                matlab.ui.control.Label
        k4EditField                     matlab.ui.control.NumericEditField
        INPUTSLabel                     matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        NatureofthesystemTextAreaLabel  matlab.ui.control.Label
        NatureofthesystemTextArea       matlab.ui.control.TextArea
        ConditionsforstableTextAreaLabel  matlab.ui.control.Label
        ConditionsforstableTextArea     matlab.ui.control.TextArea
        ConditionsformarginalstableTextAreaLabel  matlab.ui.control.Label
        ConditionsformarginalstableTextArea  matlab.ui.control.TextArea
        ConditionsforunstableTextAreaLabel  matlab.ui.control.Label
        ConditionsforunstableTextArea   matlab.ui.control.TextArea
        Column1dataTextAreaLabel        matlab.ui.control.Label
        Column1dataTextArea             matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Take inputs from the panel into variables.
            
            k1 = app.k1EditField.Value;
            k2 = app.k2EditField.Value;
            k3 = app.k3EditField.Value;
            k4 = app.k4EditField.Value;
            kvector = [k1 k2 k3 k4];
            
            % K is a symbol as we have to compute its range
            syms K
            assume(K,{'real', 'positive'})
            
            % The root polynomial looks like as
            % a*s^4 + b*s^3 c*s^2 + d*s^1 + e + K = 0
            %
            % Now we will commpute values of these coeff
            
            a=1;
            b=0;
            c=0;
            d=0;
            e=1;
            
            for i = 1:4
                b = b + kvector(i);
                e = e*kvector(i);
                for j = i+1:4
                    c = c + kvector(i)*kvector(j);
                    for k = j+1:4
                        d = d + kvector(i)*kvector(j)*kvector(k);
                    end
                end
            end
            
            % Now we will compute the RH table
            % 1) a  c  e+K
            % 2) b  d  0
            % 3) x  y  0
            % 4) z  0  0
            % 5) w  0  0
            
            %% ROW 2
            
            % set flags to 1 if elements in row 2 are zero
            if(b==0)
                flag_b_zero=1;
            else
                flag_b_zero=0;
            end
            
            if(d==0)
                flag_d_zero=1;
            else
                flag_d_zero=0;
            end
            
            
            % The first element of any row of the Routh array is zero.
            if(flag_b_zero==1 && flag_d_zero==0)
                syms b
                assume(b,{'real', 'positive'})
                flag_take_limit_b = 1;
            else
                flag_take_limit_b = 0;
            end
            
            % All the elements of second row of the Routh array are zero.
            if(flag_b_zero==1 && flag_d_zero==1)
                % Auxiliary equation: a*s^4 + c*s^2 + e + K
                % diff: 4*a*s^3 + 2*c*s
                flag_row2_zero = 1;
                b = 4*a;
                d = 2*c;
            else
                flag_row2_zero = 0;
            end
            
            %% ROW 3
            
            % using algo for RH table for row 3
            x = (b*c-a*d)/b;
            y = (e+K);
            
            % set flags to 1 if elements in row 3 are zero
            if(x==0)
                flag_x_zero=1;
            else
                flag_x_zero=0;
            end
            
            % The first element of row 3 of the Routh array is zero.
            if(flag_x_zero==1)
                syms x
                assume(x,{'real', 'positive'})
                flag_take_limit_x = 1;
            else
                flag_take_limit_x = 0;
            end
            
            
            %% ROW 4
            
            % Computing row 4
            z = (x*d-y*b)/x;
            
            if(z==0)
                flag_z=1;
            else
                flag_z=0;
            end
            
            % All the elements of 4th row of the Routh array are zero.
            if(flag_z==1)
                % Auxiliary equation: x*s^2 + y
                % diff: x*s
                z=x;
                flag_row4_zero = 1;
            else
                flag_row4_zero = 0;
            end
            
            %% ROW 5
            
            w = y;
            
            %% Apply limits
            
            % column 1 of the RH table
            col1 = [a b x z w];
            
            if(flag_take_limit_b == 1)
                col1 = limit(col1,b,0,'right');
            end
            
            if(flag_take_limit_x == 1)
                col1 = limit(col1,x,0,'right');
            end
            
            app.Column1dataTextArea.Value = char(col1);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {422, 422};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {374, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.k1Label = uilabel(app.LeftPanel);
            app.k1Label.Text = 'k1';
            app.k1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.k2EditFieldLabel = uilabel(app.LeftPanel);
            app.k2EditFieldLabel.Text = 'k2';
            app.k2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.k3EditFieldLabel = uilabel(app.LeftPanel);
            app.k3EditFieldLabel.Text = 'k3';
            app.k3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.k4EditFieldLabel = uilabel(app.LeftPanel);
            app.k4EditFieldLabel.Text = 'k4';
            app.k4EditField = uieditfield(app.LeftPanel, 'numeric');
            app.INPUTSLabel = uilabel(app.LeftPanel);
            app.INPUTSLabel.Text = 'INPUTS';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'TF.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.NatureofthesystemTextAreaLabel = uilabel(app.RightPanel);
            app.NatureofthesystemTextAreaLabel.Text = 'Nature of the system';
            app.NatureofthesystemTextArea = uitextarea(app.RightPanel);
            app.ConditionsforstableTextAreaLabel = uilabel(app.RightPanel);
            app.ConditionsforstableTextAreaLabel.Text = 'Conditions for stable';
            app.ConditionsforstableTextArea = uitextarea(app.RightPanel);
            app.ConditionsformarginalstableTextAreaLabel = uilabel(app.RightPanel);
            app.ConditionsformarginalstableTextAreaLabel.Text = {'Conditions for marginal '; 'stable'};
            app.ConditionsformarginalstableTextArea = uitextarea(app.RightPanel);
            app.ConditionsforunstableTextAreaLabel = uilabel(app.RightPanel);
            app.ConditionsforunstableTextAreaLabel.Text = 'Conditions for unstable';
            app.ConditionsforunstableTextArea = uitextarea(app.RightPanel);
            app.Column1dataTextAreaLabel = uilabel(app.RightPanel);
            app.Column1dataTextAreaLabel.Text = 'Column1 data';
            app.Column1dataTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end